classdef Lab2_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        RightCameraPanel      matlab.ui.container.Panel
        yRightEditField       matlab.ui.control.NumericEditField
        yRightEditFieldLabel  matlab.ui.control.Label
        yLeftEditField        matlab.ui.control.NumericEditField
        yLeftEditFieldLabel   matlab.ui.control.Label
        LeftCameraPanel       matlab.ui.container.Panel
        xRightEditField       matlab.ui.control.NumericEditField
        xRightEditFieldLabel  matlab.ui.control.Label
        xLeftEditField        matlab.ui.control.NumericEditField
        xLeftEditFieldLabel   matlab.ui.control.Label
        Label                 matlab.ui.control.Label
        ComputeButton         matlab.ui.control.Button
    end

    
    properties (Access = public)
        b;       % baseline [mm]
        f;       % focal length [mm]
        ps;      % pixel size [mm]
        xNumPix; % total number of pixels in x direction of the camera
        yNumPix; % total number of pixels in y direction of the camera
        cxLeft;  % left camera x center [px]
        cyLeft;  % left camera y center [px]
        cxRight; % right camera x center [px]
        cyRight; % right camera y center [px]
    end
    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            disp("Init function has started.");
            app.b = 60;       % baseline [mm]
            app.f = 6;       % focal length [mm]
            app.ps = .006;      % pixel size [mm]
            app.xNumPix = 752; % total number of pixels in x direction of the camera
            app.yNumPix = 480; % total number of pixels in y direction of the camera
            app.cxLeft = app.xNumPix/2;  % left camera x center [px]
            app.cyLeft = app.yNumPix/2;  % left camera y center [px]
            app.cxRight = app.xNumPix/2; % right camera x center [px]
            app.cyRight = app.yNumPix/2; % right camera y center [px]
        end

        % Button pushed function: ComputeButton
        function ComputeButtonPushed(app, event)
            disp("Cb function has started.");
            xLeft = app.xLeftEditField.Value;
            yLeft = app.yLeftEditField.Value;
            xRight = app.xRightEditField.Value;
            yRight = app.yRightEditField.Value;
            disp(d)
            d = (abs((xLeft-app.cxLeft)-(xRight-app.cxRight)) * app.ps); % distance in [mm]
            Z = (app.b * app.f)/(d*1000); % depth [m]
            X = (Z * (xLeft-app.cxLeft) * app.ps)/app.f;
            Y = (Z * (yLeft-app.cyLeft) * app.ps)/app.f;
            fprintf('\nThe depth in [m] is %d \n',Z)
            app.Label.Text = ['The depth in [m] is ' num2str(Z)];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ComputeButton = uibutton(app.UIFigure, 'push');
            app.ComputeButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeButtonPushed, true);
            app.ComputeButton.Text = 'Compute';
            app.Label = uilabel(app.UIFigure);
            app.LeftCameraPanel = uipanel(app.UIFigure);
            app.LeftCameraPanel.Title = 'Left Camera';
            app.xLeftEditFieldLabel = uilabel(app.LeftCameraPanel);
            app.xLeftEditFieldLabel.Text = 'xLeft';
            app.xLeftEditField = uieditfield(app.LeftCameraPanel, 'numeric');
            app.xRightEditFieldLabel = uilabel(app.LeftCameraPanel);
            app.xRightEditFieldLabel.Text = 'xRight';
            app.xRightEditField = uieditfield(app.LeftCameraPanel, 'numeric');
            app.RightCameraPanel = uipanel(app.UIFigure);
            app.RightCameraPanel.Title = 'Right Camera';
            app.yLeftEditFieldLabel = uilabel(app.RightCameraPanel);
            app.yLeftEditFieldLabel.Text = 'yLeft';
            app.yLeftEditField = uieditfield(app.RightCameraPanel, 'numeric');
            app.yRightEditFieldLabel = uilabel(app.RightCameraPanel);
            app.yRightEditFieldLabel.Text = 'yRight';
            app.yRightEditField = uieditfield(app.RightCameraPanel, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Lab2_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end