classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TabGroup                    matlab.ui.container.TabGroup
        CentroidTab                 matlab.ui.container.Tab
        ImagePairSelectionDropDown  matlab.ui.control.DropDown
        ImagePairSelectionDropDownLabel  matlab.ui.control.Label
        GUIBallDetectionAlgorithmVisualizationLabel  matlab.ui.control.Label
        UIAxes                      matlab.ui.control.UIAxes
        UIAxes2                     matlab.ui.control.UIAxes
        PlotTab                     matlab.ui.container.Tab
        PLotButton                  matlab.ui.control.Button
        UIAxes3                     matlab.ui.control.UIAxes
        ValuesTab                   matlab.ui.container.Tab
        UITable                     matlab.ui.control.Table
    end

    % Properties that correspond to app components
    properties (Access = public)
        UIFig            matlab.ui.Figure
        LoadImageButton  matlab.ui.control.Button
        DetectButton     matlab.ui.control.Button
        LeftAxes         matlab.ui.control.UIAxes
        RightAxes        matlab.ui.control.UIAxes
        XPositionLabel   matlab.ui.control.Label
        YPositionLabel   matlab.ui.control.Label
        ZPositionLabel   matlab.ui.control.Label
    end

    properties (Access = private)
        leftImage          % Stores the left image
        rightImage         % Stores the right image
        leftCentroid       % Centroid of the ball in the left image
        rightCentroid      % Centroid of the ball in the right image
        leftRadius         % Radius of the ball in the left image
        rightRadius        % Radius of the ball in the left image
        ballPositions = containers.Map('KeyType', 'double', 'ValueType', 'any'); % Hash table to store ball positions
        ValuesTable % The table to display the ball positions
    end

    methods (Access = private)

        % Helper function for ball detection
        function [center, radius] = detectBallCentroid(app, image, axesHandle)
           % Convert image to grayscale
            grayImage = rgb2gray(image);
        
            % Apply edge detection
            edges = edge(grayImage, 'canny');
        
            % Use the Hough Transform to detect circles
            [centers, radii, metric] = imfindcircles(edges, [15 100], 'ObjectPolarity', 'bright', 'Sensitivity', 0.96, 'EdgeThreshold', 0.2);
        
            % If no circles were found, return empty values
            if isempty(centers)
                center = [];
                radius = [];
                return;
            end
        
            % Select the circle with the highest metric value
            [~, idx] = max(metric);
            center = centers(idx, :);
            radius = radii(idx);
        
            % Draw the circle and centroid on the image
            imshow(image, 'Parent', axesHandle);
            hold(axesHandle, 'on');
            viscircles(axesHandle, center, radius, 'EdgeColor', 'r', 'LineWidth', 2, 'EnhanceVisibility', false);
            plot(axesHandle, center(1), center(2), 'r+', 'MarkerSize', 10, 'LineWidth', 2);
            hold(axesHandle, 'off');
        end

        % Helper function to calculate 3D position
        function [X, Y, Z] = compute3DPosition(app, leftCentroid, rightCentroid)
            % Dummy example for stereo triangulation
            baseline = 100; % Distance between the two camera centers
            focalLength = 8; % Focal length of the camera
            if isempty(leftCentroid) || isempty(rightCentroid)
                X = NaN;
                Y = NaN;
                Z = NaN;
                return;
            end
            disparity = leftCentroid(1) - rightCentroid(1);
            Z = (focalLength * baseline) / disparity;
            X = leftCentroid(1) * Z / focalLength;
            Y = leftCentroid(2) * Z / focalLength;
        end
        
        % function isValid = isValidCircle(app, center, radius, contrastAdjustedImage)
        %     % Create a binary image with a circle ROI
        %     [xx, yy] = ndgrid((1:size(contrastAdjustedImage, 1)) - center(2), ...
        %                       (1:size(contrastAdjustedImage, 2)) - center(1));
        %     roi = (xx.^2 + yy.^2) <= radius^2;
        % 
        %     % Check the ROI for uniformity or other characteristics typical of the ball
        %     % This could include checking the standard deviation of pixel values in the ROI
        %     % or performing a color consistency check if the ball has a distinct color
        %     maskedImage = contrastAdjustedImage .* uint8(roi);
        %     roiStats = regionprops(roi, maskedImage, 'MeanIntensity', 'Area');
        % 
        %     % Define some threshold based on your observations
        %     someThreshold = 0.5;  % Adjust this value based on your specific needs
        % 
        %     % Check if the circle is valid
        %     if ~isempty(roiStats) && roiStats.Area > 0
        %         % Calculate the standard deviation of pixel values in the ROI
        %         pixelValues = nonzeros(maskedImage);
        %         stdDev = std(double(pixelValues));
        % 
        %         % Check if the standard deviation is below the threshold
        %         isValid = stdDev < someThreshold;
        %     else
        %         isValid = false;
        %     end
        % end

        function printBallPositions(app, ballPositions)
            % Extract all values from the map
            allValues = values(ballPositions);
        
            % Iterate over the values and print each one
            for i = 1:length(allValues)
                disp(['Value ' num2str(i) ':']);
                disp(allValues{i});
            end
        end 
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: ImagePairSelectionDropDown
        function ImagePairSelectionDropDownValueChanged(app, event)
            selectedOption = app.ImagePairSelectionDropDown.Value;
            % Ensure that a valid option is selected
            if strcmp(selectedOption, 'No Selection')
                % Clear axes or show a default image/message
                cla(app.UIAxes);
                cla(app.UIAxes2);
                return;
            end
        
            pairNumber = str2double(selectedOption(end)); % Extract the number from the option string
        
            % Construct the file paths for the left and right images
            leftImagePath = sprintf('/home/tohab0g/Projects/CPET563-ESD2/Lab3/doc/left%d.jpg', pairNumber);
            rightImagePath = sprintf('/home/tohab0g/Projects/CPET563-ESD2/Lab3/doc/right%d.jpg', pairNumber);
        
            % Load and display the left and right images
            app.leftImage = imread(leftImagePath);
            app.rightImage = imread(rightImagePath);
            imshow(app.leftImage, 'Parent', app.UIAxes);
            imshow(app.rightImage, 'Parent', app.UIAxes2);
        
            % Automatically adjust axes limits to fit the image dimensions
            app.UIAxes.XLim = [0.5, size(app.leftImage, 2) + 0.5];
            app.UIAxes.YLim = [0.5, size(app.leftImage, 1) + 0.5];
            app.UIAxes2.XLim = [0.5, size(app.rightImage, 2) + 0.5];
            app.UIAxes2.YLim = [0.5, size(app.rightImage, 1) + 0.5];
        
            % Detect the ball centroid in both images
            [app.leftCentroid, leftRadius] = app.detectBallCentroid(app.leftImage, app.UIAxes);
            [app.rightCentroid, rightRadius] = app.detectBallCentroid(app.rightImage, app.UIAxes2);
        
            % Compute the 3D position of the ball
            [X, Y, Z] = app.compute3DPosition(app.leftCentroid, app.rightCentroid);
            if ~isempty(app.leftCentroid) && ~isempty(app.rightCentroid)
                % Store the position in the ballPositions map
                app.ballPositions(pairNumber) = [X; Y; Z];
                fprintf('Picture Pair %d: Left (X, Y, Z) = (%.2f, %.2f, %.2f), Right (X, Y, Z) = (%.2f, %.2f, %.2f)\n', pairNumber, X, Y, Z, X, Y, Z);
            else
                % Handle the case where the ball was not found
                fprintf('Ball not detected in one or both images of Picture Pair %d.\n', pairNumber);
            end
        end

        % Button pushed function: PLotButton
        function PLotButtonPushed(app, event)
            % Plot the 3D positions in the Plot tab
            % Print the keys and their corresponding values
            keys = app.ballPositions.keys;
            for i = 1:length(keys)
                disp(['Key ' num2str(keys{i}) ':']);
                disp(app.ballPositions(keys{i}));
            end
        
            % Plot the 3D positions in the Plot tab
            positions = cell2mat(values(app.ballPositions))';
            plot3(app.UIAxes3, positions(1,:), positions(2,:), positions(3,:), 'b-o', 'LineWidth', 2, 'MarkerSize', 8);
            hold(app.UIAxes3, "on");
            grid(app.UIAxes3, 'on');
            
            % Update the Values table
            % pairs = keys(app.ballPositions);
            % data = cell(length(pairs), 4);
            % for i = 1:length(pairs)
            %     data{i, 1} = pairs{i};
            %     pos = app.ballPositions(pairs{i});
            %     data{i, 2} = pos(1);
            %     data{i, 3} = pos(2);
            %     data{i, 4} = pos(3);
            % end
            % app.ValuesTable = uitable(app.ValuesTab);
            % app.ValuesTable.= data;
            % app.ValuesTabable.ColumnName = {'Pair', 'X', 'Y', 'Z'};
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.CentroidTab = uitab(app.TabGroup);
            app.CentroidTab.Title = 'Centroid';
            app.UIAxes2 = uiaxes(app.CentroidTab);
            title(app.UIAxes2, 'Right Picture Ball Position')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.XLim = [0 1];
            app.UIAxes2.YLim = [0 1];
            app.UIAxes2.ZLim = [0 1];
            app.UIAxes = uiaxes(app.CentroidTab);
            title(app.UIAxes, 'Left Picture Ball Position')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [0 1];
            app.UIAxes.YLim = [0 1];
            app.UIAxes.ZLim = [0 1];
            app.GUIBallDetectionAlgorithmVisualizationLabel = uilabel(app.CentroidTab);
            app.GUIBallDetectionAlgorithmVisualizationLabel.Text = 'GUI: Ball Detection Algorithm Visualization';
            app.ImagePairSelectionDropDownLabel = uilabel(app.CentroidTab);
            app.ImagePairSelectionDropDownLabel.Text = 'Image Pair Selection:';
            app.ImagePairSelectionDropDown = uidropdown(app.CentroidTab);
            app.ImagePairSelectionDropDown.Items = {'No Selection', 'Option 5', 'Option 6', 'Option 7', 'Option 8', 'Option 9'};
            app.ImagePairSelectionDropDown.ValueChangedFcn = createCallbackFcn(app, @ImagePairSelectionDropDownValueChanged, true);
            app.ImagePairSelectionDropDown.Value = 'No Selection';
            app.PlotTab = uitab(app.TabGroup);
            app.PlotTab.Title = 'Plot';
            app.UIAxes3 = uiaxes(app.PlotTab);
            title(app.UIAxes3, 'Centroid Plot')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.PLotButton = uibutton(app.PlotTab, 'push');
            app.PLotButton.ButtonPushedFcn = createCallbackFcn(app, @PLotButtonPushed, true);
            app.PLotButton.Text = 'PLot';
            app.ValuesTab = uitab(app.TabGroup);
            app.ValuesTab.Title = 'Values';
            app.UITable = uitable(app.ValuesTab);
            app.UITable.ColumnName = {'Pair'; 'X'; 'Y'; 'Z'};
            app.UITable.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end